classdef DataExoFlexAppGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        MovementONEButton       matlab.ui.control.Button
        DisconnectButton        matlab.ui.control.Button
        SensorsstatusLampLabel  matlab.ui.control.Label
        SensorsstatusLamp       matlab.ui.control.Lamp
        StateLampLabel          matlab.ui.control.Label
        StateLamp               matlab.ui.control.Lamp
        Label                   matlab.ui.control.Label
        ApptosavedatareceivedbyIMUEMGandFlexSensLabel  matlab.ui.control.Label
        MovementTWOButton       matlab.ui.control.Button
        MovementTHREEButton     matlab.ui.control.Button
        ConnectButton           matlab.ui.control.Button
        EditFieldLabel          matlab.ui.control.Label
        EditField               matlab.ui.control.EditField
        SamplesEditFieldLabel   matlab.ui.control.Label
        SamplesEditField        matlab.ui.control.NumericEditField
        IMU1COMDropDownLabel    matlab.ui.control.Label
        IMU1COMDropDown         matlab.ui.control.DropDown
        IMU2COMDropDownLabel    matlab.ui.control.Label
        IMU2COMDropDown         matlab.ui.control.DropDown
        ARDCOMDropDownLabel     matlab.ui.control.Label
        ARDCOMDropDown          matlab.ui.control.DropDown
        TestModeSwitchLabel     matlab.ui.control.Label
        TestModeSwitch          matlab.ui.control.Switch
    end


    methods (Access = private)
        
        function lCOM_Port = getAvailableComPort(app)
            try
                s=serial('IMPOSSIBLE_NAME_ON_PORT');fopen(s); 
            catch
                lErrMsg = lasterr;
            end
            lIndex1 = findstr(lErrMsg,'COM');
            lIndex2 = findstr(lErrMsg,'Use')-3;
            lComStr = lErrMsg(lIndex1:lIndex2);
            lIndexDot = findstr(lComStr,',');
            if isempty(lIndex1)
                lCOM_Port{1}='';
                return;
            end
            if isempty(lIndexDot)
                lCOM_Port{1}=lComStr;
                return;
            end
            lCOM_Port{1} = lComStr(1:lIndexDot(1)-1);
            for i=1:numel(lIndexDot)+1
                if (i==1)
                    lCOM_Port{1,1} = lComStr(1:lIndexDot(i)-1);
                elseif (i==numel(lIndexDot)+1)
                    lCOM_Port{i,1} = lComStr(lIndexDot(i-1)+2:end);       
                else
                    lCOM_Port{i,1} = lComStr(lIndexDot(i-1)+2:lIndexDot(i)-1);
                end
            end
        end
        
        function blockCOMButtonsFnc(app)
            app.IMU1COMDropDownLabel.Enable = 'off';
            app.IMU1COMDropDown.Enable = 'off';
            app.IMU2COMDropDownLabel.Enable = 'off';
            app.IMU2COMDropDown.Enable = 'off';
            app.ARDCOMDropDownLabel.Enable = 'off';
            app.ARDCOMDropDown.Enable = 'off';
            app.TestModeSwitch.Enable = 'off';
        end
        
        function unblockCOMButtonsFnc(app)
            app.IMU1COMDropDownLabel.Enable = 'on';
            app.IMU1COMDropDown.Enable = 'on';
            app.IMU2COMDropDownLabel.Enable = 'on';
            app.IMU2COMDropDown.Enable = 'on';
            app.ARDCOMDropDownLabel.Enable = 'on';
            app.ARDCOMDropDown.Enable = 'on';
            app.TestModeSwitch.Enable = 'on';
        end
        
        function blockButtonsFnc(app)
            app.DisconnectButton.Enable = 'off';
            app.MovementONEButton.Enable = 'off';
            app.MovementTWOButton.Enable = 'off';
            app.MovementTHREEButton.Enable = 'off';
        end
        
        function unblockButtonsFnc(app)
            app.DisconnectButton.Enable = 'on';
            app.MovementONEButton.Enable = 'on';
            app.MovementTWOButton.Enable = 'on';
            app.MovementTHREEButton.Enable = 'on';
        end
        
        function posSensors = positionCalcFnc(app, pitchS1, rollS1, yawS1, pitchS2, rollS2, yawS2)
            %% Parameters
            length1 = 1;
            length2 = 1;
            posIni = [0 0 0];
            %% Position sensor 1
            xS1 = posIni(1) + cos(yawS1)*cos(pitchS1)*length1;
            yS1 = posIni(2) + sin(yawS1)*cos(pitchS1)*length1;
            zS1 = posIni(3) + sin(pitchS1)*length1;
            
            %% Position sensor 2
            xS2 = xS1 + cos(yawS2)*cos(pitchS2)*length2;
            yS2 = yS1 + sin(yawS2)*cos(pitchS2)*length2;
            zS2 = zS1 + sin(pitchS2)*length2;
            %% Angle between two vectors
            angleR = subspace([xS1;yS1;zS1],[xS2;yS2;zS2])*2;
            %% Return
            posSensors = [xS1 yS1 zS1 xS2 yS2 zS2 angleR];
        end
        
        function MovementButton(app, Mvmt)
            if app.TestModeSwitch.Value == '0'
                global a; global lpSensor1; global lpSensor2;
                blockButtonsFnc(app);
                samples = app.SamplesEditField.Value;
                nCount = 1; maxStep = samples + 1;
                VA0 = []; VA1 = []; VA2=[];
                anglesSensors = []; linearAcc = []; posSensors = [];
                Sample = [];
                % Counter
                tim = 0;
                maxTime = 3;
                while tim <= maxTime
                    app.StateLamp.Color = 'b';
                    text = int2str(maxTime - tim);
                    app.Label.Text = text;
                    if text == '3' || text == '1'
                        app.StateLamp.Color = 'y';
                    end
                    pause(1)
                    tim = tim + 1;
                end
                app.Label.Text = 'READING DATA ...';
                app.StateLamp.Color = 'g';
                % Data Adquisition
                while nCount < maxStep
                    % Reading Method
                    VA0(nCount,:) = readVoltage(a, 'A0');                 % Sensor 1 connected to input A0.
                    VA1(nCount,:) = readVoltage(a, 'A1');                 % Sensor 2 connected to input A1.
                    VA2(nCount,:) = readVoltage(a, 'A2');                 % Sensor 3 connected to input A2.
                    dataIMU1 = lpSensor1.getCurrentSensorData();        % get current IMU1 sensor data
                    dataIMU2 = lpSensor2.getCurrentSensorData();        % get current IMU2 sensor data
                    % Storing data on Matrix
                    [pS1, rS1, yS1] = quat2angle(dataIMU1.quat, 'YXZ');
                    [pS2, rS2, yS2] = quat2angle(dataIMU2.quat, 'YXZ');
                    anglesSensors(nCount,:) = [pS1, rS1, yS1, pS2, rS2, yS2];
                    linearAcc(nCount,:) = [dataIMU1.linAcc, dataIMU2.linAcc];
                    posSensors(nCount,:) = positionCalcFnc(app, pS1, rS1, yS1, pS2, rS2, yS2);
                    Sample(nCount,:) = nCount;
                    % Blinking
                    % Progress
                    app.StateLampLabel.Text = int2str(maxStep - nCount);
                    nCount = nCount + 1;
                end
                pause(1.0);
                %app.ProgressGauge.Value = 0;
                app.StateLamp.Color = 'b';
                % Crate File
                app.StateLampLabel.Text = 'State';
                app.Label.Text = 'SAVING DATA ...';
                folderName = 'testsData';
                [status, msg, msgID] = mkdir(folderName);
                dir = [pwd, '\',folderName];
                disp(dir)
                editField = app.EditField.Value;
                S = char(datetime('now','Format','yyyy-MM-dd''T''HHmmss'));
                fileName = [editField,Mvmt,S,'.txt'];
                try
                    fileID = fopen(fullfile(dir,fileName),'wt');
                    fprintf(fileID,'%12s %12s %12s %12s %12s %12s %12s %12s %12s %12s %12s %12s %12s %12s %12s %12s %12s %12s %12s %12s %12s %12s %12s\r\n','timestamp','VA0', 'VA1', 'VA2', 'pos1X', 'pos1Y', 'pos1Z', 'pitchS1', 'rollS1', 'yawS1', 'linAccX1', 'linAccY1', 'linAccZ1', 'pos2X', 'pos2Y', 'pos2Z', 'pitchS2', 'rollS2', 'yawS2', 'linAccX2', 'linAccY2', 'linAccZ2', 'Angle');
                    fprintf(fileID,'%12.0f %12.4f %12.4f %12.4f %12.4f %12.4f %12.4f %12.4f %12.4f %12.4f %12.4f %12.4f %12.4f %12.4f %12.4f %12.4f %12.4f %12.4f %12.4f %12.4f %12.4f %12.4f %12.4f\r\n',[Sample, VA0, VA1, VA2, posSensors(:,1), posSensors(:,2), posSensors(:,3), anglesSensors(:,1), anglesSensors(:,2), anglesSensors(:,3), linearAcc(:,1), linearAcc(:,2), linearAcc(:,3), posSensors(:,4), posSensors(:,5), posSensors(:,6), anglesSensors(:,4), anglesSensors(:,5), anglesSensors(:,6), linearAcc(:,4), linearAcc(:,5), linearAcc(:,6), posSensors(:,7)]');
                    fclose(fileID);
                catch msg
                     disp(msg)
                     app.Label.Text = 'ERROR SAVING DATA';
                     pause(1)
                end
                pause(1)
                %app.ProgressGaugeLabel.Text = 'Progress';
                app.Label.Text = 'Ready';
                app.StateLamp.Color = 'y';
                unblockButtonsFnc(app);
            else
                app.Label.Text = 'Reading';
                app.StateLamp.Color = 'b';
                pause(1)
                app.Label.Text = 'Ready';
                app.StateLamp.Color = 'y';
            end
        end
    end

    methods (Access = private)

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            global a; global lpSensor1; global lpSensor2;
            IMU1_COM = app.IMU1COMDropDown.Value;
            IMU2_COM = app.IMU2COMDropDown.Value;
            ARD_COM = app.ARDCOMDropDown.Value;
            % COM verification
            if app.TestModeSwitch.Value == '0'
                if isempty(IMU1_COM) || isempty(IMU2_COM) || isempty(ARD_COM) || IMU1_COM == "Select" || IMU2_COM == "Select" || ARD_COM == "Select"
                    app.Label.Text = 'Select COM';
                    return
                end
            end
            blockCOMButtonsFnc(app);
            % quat2angle installed verification
            try
                [yaw, pitch, roll] = quat2angle([1 0 1 0]);
            catch
                app.Label.Text = 'quat2angle not installed (aerotoolbox), please see: https://es.mathworks.com/help/aerotbx/ug/quat2angle.html';
                clear all;
                return
            end
            app.Label.Text = 'Connecting ... ';
            app.SensorsstatusLamp.Color = 'y';
            %clc
            if app.TestModeSwitch.Value == '0'
                % ARDUINO
                try
                    disp('Arduino ...')
                    %ard = arduino('COM7', 'Mega2560');     % COM port to which the Arduino is connected
                    a = arduino(ARD_COM, 'uno');
                catch msg
                    disp(msg)
                    app.Label.Text = 'Arduino not connected';
                    app.SensorsstatusLamp.Color = 'r';
                    unblockCOMButtonsFnc(app);
                    clear all;
                    return
                end
                % IMU SENSORS
                disp('IMU sensors ...')
                COMPort1 = IMU1_COM;          % COM port to which the IMU 1 is connected
                COMPort2 = IMU2_COM;          % COM port to which the IMU 2 is connected
                baudrate = 921600;          % rate at which information is transferred
                lpSensor1 = lpms();         % object lpms API sensor 1 given by LPMS
                lpSensor2 = lpms();         % object lpms API sensor 2 given by LPMS
                if ( ~lpSensor1.connect(COMPort1, baudrate) || ~lpSensor2.connect(COMPort2, baudrate) )
                    app.Label.Text = 'IMU Sensors not connected';
                    app.SensorsstatusLamp.Color = 'r';
                    unblockCOMButtonsFnc(app);
                    clear all;
                    return 
                end
                lpSensor1.setStreamingMode();
                lpSensor2.setStreamingMode();
            end
            % GUI visual aids
            app.Label.Text = 'Connected';
            disp('Done. All set up')
            app.SensorsstatusLamp.Color = 'g';
            app.StateLamp.Color = 'y';
            unblockButtonsFnc(app);
            app.ConnectButton.Enable = 'off';
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            global a; global lpSensor1; global lpSensor2;
            disp('Disconnecting')
            app.Label.Text = 'Disconnecting';
            if app.TestModeSwitch.Value == '0'
                if (lpSensor1.disconnect() && lpSensor2.disconnect())
                    disp('Sensors disconnected')
                end
            end
            % GUI visual aids
            app.SensorsstatusLamp.Color = 'r';
            app.StateLamp.Color = 'r';
            blockButtonsFnc(app);
            unblockCOMButtonsFnc(app);
            app.ConnectButton.Enable = 'on';
            %app.ProgressGauge.Value = 0;
            app.Label.Text = ' ';
            clear all
        end

        % Button pushed function: MovementONEButton
        function MovementONEButtonPushed(app, event)
            Mvmt = '_m1_';
            MovementButton(app, Mvmt);
        end

        % Button pushed function: MovementTWOButton
        function MovementTWOButtonPushed(app, event)
            Mvmt = '_m2_';
            MovementButton(app, Mvmt);
        end

        % Button pushed function: MovementTHREEButton
        function MovementTHREEButtonPushed(app, event)
            Mvmt = '_m3_';
            MovementButton(app, Mvmt);
        end

        % Value changed function: IMU1COMDropDown
        function IMU1COMDropDownValueChanged(app, event)
            app.IMU1COMDropDown.Items = getAvailableComPort(app);
        end

        % Value changed function: IMU2COMDropDown
        function IMU2COMDropDownValueChanged(app, event)
            app.IMU2COMDropDown.Items = getAvailableComPort(app);
        end

        % Value changed function: ARDCOMDropDown
        function ARDCOMDropDownValueChanged(app, event)
            app.ARDCOMDropDown.Items = getAvailableComPort(app);
        end

        % Value changed function: TestModeSwitch
        function TestModeSwitchValueChanged(app, event)
            value = app.TestModeSwitch.Value;
            if value == '0'
                app.ApptosavedatareceivedbyIMUEMGandFlexSensLabel.Text = 'App to save data received by IMU, EMG and FlexSens';
                app.UIFigure.Color = [0.94,0.94,0.94];
            else
                app.ApptosavedatareceivedbyIMUEMGandFlexSensLabel.Text = 'TEST MODE';
                app.UIFigure.Color = [0.80,0.80,0.80];
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.MovementONEButton = uibutton(app.UIFigure, 'push');
            app.MovementONEButton.ButtonPushedFcn = createCallbackFcn(app, @MovementONEButtonPushed, true);
            app.MovementONEButton.Enable = 'off';
            app.MovementONEButton.Text = {'Movement'; 'ONE'};
            app.DisconnectButton = uibutton(app.UIFigure, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Enable = 'off';
            app.DisconnectButton.Text = 'Disconnect';
            app.SensorsstatusLampLabel = uilabel(app.UIFigure);
            app.SensorsstatusLampLabel.Text = 'Sensors status';
            app.SensorsstatusLamp = uilamp(app.UIFigure);
            app.StateLampLabel = uilabel(app.UIFigure);
            app.StateLampLabel.Text = 'State';
            app.StateLamp = uilamp(app.UIFigure);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.ApptosavedatareceivedbyIMUEMGandFlexSensLabel = uilabel(app.UIFigure);
            app.ApptosavedatareceivedbyIMUEMGandFlexSensLabel.Text = 'App to save data received by IMU, EMG and FlexSens';
            app.MovementTWOButton = uibutton(app.UIFigure, 'push');
            app.MovementTWOButton.ButtonPushedFcn = createCallbackFcn(app, @MovementTWOButtonPushed, true);
            app.MovementTWOButton.Enable = 'off';
            app.MovementTWOButton.Text = {'Movement'; 'TWO'};
            app.MovementTHREEButton = uibutton(app.UIFigure, 'push');
            app.MovementTHREEButton.ButtonPushedFcn = createCallbackFcn(app, @MovementTHREEButtonPushed, true);
            app.MovementTHREEButton.Enable = 'off';
            app.MovementTHREEButton.Text = {'Movement'; 'THREE'};
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = '';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Value = 'Name';
            app.SamplesEditFieldLabel = uilabel(app.UIFigure);
            app.SamplesEditFieldLabel.Text = 'Samples';
            app.SamplesEditField = uieditfield(app.UIFigure, 'numeric');
            app.SamplesEditField.Value = 200;
            app.IMU1COMDropDownLabel = uilabel(app.UIFigure);
            app.IMU1COMDropDownLabel.Text = 'IMU 1 COM';
            app.IMU1COMDropDown = uidropdown(app.UIFigure);
            app.IMU1COMDropDown.Items = {'Select', 'Get COMPorts'};
            app.IMU1COMDropDown.Editable = 'on';
            app.IMU1COMDropDown.ValueChangedFcn = createCallbackFcn(app, @IMU1COMDropDownValueChanged, true);
            app.IMU1COMDropDown.Value = 'Select';
            app.IMU2COMDropDownLabel = uilabel(app.UIFigure);
            app.IMU2COMDropDownLabel.Text = 'IMU 2 COM';
            app.IMU2COMDropDown = uidropdown(app.UIFigure);
            app.IMU2COMDropDown.Items = {'Select', 'Get COMPorts'};
            app.IMU2COMDropDown.Editable = 'on';
            app.IMU2COMDropDown.ValueChangedFcn = createCallbackFcn(app, @IMU2COMDropDownValueChanged, true);
            app.IMU2COMDropDown.Value = 'Select';
            app.ARDCOMDropDownLabel = uilabel(app.UIFigure);
            app.ARDCOMDropDownLabel.Text = 'ARD COM';
            app.ARDCOMDropDown = uidropdown(app.UIFigure);
            app.ARDCOMDropDown.Items = {'Select', 'Get COMPorts'};
            app.ARDCOMDropDown.Editable = 'on';
            app.ARDCOMDropDown.ValueChangedFcn = createCallbackFcn(app, @ARDCOMDropDownValueChanged, true);
            app.ARDCOMDropDown.Value = 'Select';
            app.TestModeSwitchLabel = uilabel(app.UIFigure);
            app.TestModeSwitchLabel.Text = 'Test Mode';
            app.TestModeSwitch = uiswitch(app.UIFigure, 'slider');
            app.TestModeSwitch.Items = {'0', '1'};
            app.TestModeSwitch.ValueChangedFcn = createCallbackFcn(app, @TestModeSwitchValueChanged, true);
            app.TestModeSwitch.Value = '0';
        end
    end

    methods (Access = public)

        % Construct app
        function app = DataExoFlexAppGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end