classdef DataExoFlexAppGUIV2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        STARTButton             matlab.ui.control.Button
        DisconnectButton        matlab.ui.control.Button
        SensorsstatusLampLabel  matlab.ui.control.Label
        SensorsstatusLamp       matlab.ui.control.Lamp
        StateLampLabel          matlab.ui.control.Label
        StateLamp               matlab.ui.control.Lamp
        Label                   matlab.ui.control.Label
        ApptosavedatareceivedbyIMUEMGandFlexSensLabel  matlab.ui.control.Label
        STOPButton              matlab.ui.control.Button
        ConnectButton           matlab.ui.control.Button
        EditFieldLabel          matlab.ui.control.Label
        EditField               matlab.ui.control.EditField
        IMU1COMDropDownLabel    matlab.ui.control.Label
        IMU1COMDropDown         matlab.ui.control.DropDown
        IMU2COMDropDownLabel    matlab.ui.control.Label
        IMU2COMDropDown         matlab.ui.control.DropDown
        ARDCOMDropDownLabel     matlab.ui.control.Label
        ARDCOMDropDown          matlab.ui.control.DropDown
        TestModeSwitchLabel     matlab.ui.control.Label
        TestModeSwitch          matlab.ui.control.Switch
    end


    properties (Access = private)
        Arduino % Description
        lpSensor1 % Description
        lpSensor2 % Description
        stopBtnPressed % Description
    end

    methods (Access = private)
        
        function blockCOMButtonsFnc(app)
            app.IMU1COMDropDownLabel.Enable = 'off';
            app.IMU1COMDropDown.Enable = 'off';
            app.IMU2COMDropDownLabel.Enable = 'off';
            app.IMU2COMDropDown.Enable = 'off';
            app.ARDCOMDropDownLabel.Enable = 'off';
            app.ARDCOMDropDown.Enable = 'off';
            app.TestModeSwitch.Enable = 'off';
        end
        
        function unblockCOMButtonsFnc(app)
            app.IMU1COMDropDownLabel.Enable = 'on';
            app.IMU1COMDropDown.Enable = 'on';
            app.IMU2COMDropDownLabel.Enable = 'on';
            app.IMU2COMDropDown.Enable = 'on';
            app.ARDCOMDropDownLabel.Enable = 'on';
            app.ARDCOMDropDown.Enable = 'on';
            app.TestModeSwitch.Enable = 'on';
        end
        
        function blockButtonsFnc(app)
            app.DisconnectButton.Enable = 'off';
            app.STARTButton.Enable = 'off';
        end
        
        function unblockButtonsFnc(app)
            app.DisconnectButton.Enable = 'on';
            app.STARTButton.Enable = 'on';
            app.STOPButton.Enable = 'on';
        end
        
        function lCOM_Port = getAvailableComPort(app)
            try
                s=serial('IMPOSSIBLE_NAME_ON_PORT');fopen(s); 
            catch
                lErrMsg = lasterr;
            end
            lIndex1 = findstr(lErrMsg,'COM');
            lIndex2 = findstr(lErrMsg,'Use')-3;
            lComStr = lErrMsg(lIndex1:lIndex2);
            lIndexDot = findstr(lComStr,',');
            if isempty(lIndex1)
                lCOM_Port{1}='';
                return;
            end
            if isempty(lIndexDot)
                lCOM_Port{1}=lComStr;
                return;
            end
            lCOM_Port{1} = lComStr(1:lIndexDot(1)-1);
            for i=1:numel(lIndexDot)+1
                if (i==1)
                    lCOM_Port{1,1} = lComStr(1:lIndexDot(i)-1);
                elseif (i==numel(lIndexDot)+1)
                    lCOM_Port{i,1} = lComStr(lIndexDot(i-1)+2:end);       
                else
                    lCOM_Port{i,1} = lComStr(lIndexDot(i-1)+2:lIndexDot(i)-1);
                end
            end
        end
        
        function [r1,r2,r3] = funcQuat2angle(app, q)
            qin = quatnormalize( q );
            
            [r1,r2,r3] = threeaxisrot( 2.*(qin(:,2).*qin(:,4) + qin(:,1).*qin(:,3)), ...
                                       qin(:,1).^2 - qin(:,2).^2 - qin(:,3).^2 + qin(:,4).^2, ...
                                      -2.*(qin(:,3).*qin(:,4) - qin(:,1).*qin(:,2)), ...
                                       2.*(qin(:,2).*qin(:,3) + qin(:,1).*qin(:,4)), ...
                                       qin(:,1).^2 - qin(:,2).^2 + qin(:,3).^2 - qin(:,4).^2); 
                                   
            function [r1,r2,r3] = threeaxisrot(r11, r12, r21, r31, r32)
                % find angles for rotations about X, Y, and Z axes
                r1 = atan2( r11, r12 );
                r2 = asin( r21 );
                r3 = atan2( r31, r32 );
            end
        end
        
        function posSensors = positionCalcFnc(app, pitchS1, rollS1, yawS1, pitchS2, rollS2, yawS2)
            %% Parameters
            length1 = 1;
            length2 = 1;
            posIni = [0 0 0];
            %% Position sensor 1
            xS1 = posIni(1) + cos(yawS1)*cos(pitchS1)*length1;
            yS1 = posIni(2) + sin(yawS1)*cos(pitchS1)*length1;
            zS1 = posIni(3) + sin(pitchS1)*length1;
            
            %% Position sensor 2
            xS2 = xS1 + cos(yawS2)*cos(pitchS2)*length2;
            yS2 = yS1 + sin(yawS2)*cos(pitchS2)*length2;
            zS2 = zS1 + sin(pitchS2)*length2;
            %% Angle between two vectors
            angleR = subspace([xS1;yS1;zS1],[xS2;yS2;zS2])*2;
            %% Return
            posSensors = [xS1 yS1 zS1 xS2 yS2 zS2 angleR];
        end
        
        function MovementButton(app, Mvmt)
            if app.TestModeSwitch.Value == '0'
                blockButtonsFnc(app);
                nCount = 1;
                VA0 = []; VA1 = []; VA2=[];
                anglesSensors = []; linearAcc = []; posSensors = [];
                Sample = []; timeStamp = [];
                % Counter
                tim = 0;
                maxTime = 3;
                while tim <= maxTime
                    app.StateLamp.Color = 'b';
                    text = int2str(maxTime - tim);
                    app.Label.Text = text;
                    if text == '3' || text == '1'
                        app.StateLamp.Color = 'y';
                    end
                    pause(1)
                    tim = tim + 1;
                end
                app.Label.Text = 'READING DATA ...';
                app.StateLamp.Color = 'g';
                % Data Adquisition
                flagIMUData = false;
                now = tic;
                while app.stopBtnPressed
                    % Reading Method
                    dataIMU1 = app.lpSensor1.getQueueSensorData();        % get queue IMU1 sensor data
                    dataIMU2 = app.lpSensor2.getQueueSensorData();        % get queue IMU2 sensor data
                    
                    % Sync Method
                    if (~isempty(dataIMU1)) && (~isempty(dataIMU2))
                        flagIMUData = true;
                    elseif (~isempty(dataIMU1)) && (isempty(dataIMU2))
                        while (isempty(dataIMU2))
                            dataIMU2 = app.lpSensor2.getQueueSensorData();
                            drawnow()
                        end
                        flagIMUData = true;
                    elseif (~isempty(dataIMU2)) && (isempty(dataIMU1))
                        while (isempty(dataIMU1))
                            dataIMU1 = app.lpSensor1.getQueueSensorData();
                            drawnow()
                        end
                        flagIMUData = true;
                    elseif (isempty(dataIMU2)) && (isempty(dataIMU1))
                        flagIMUData = false;
                    end

                    % Storing data on Matrix
                    if flagIMUData
                        [pS1, rS1, yS1] = funcQuat2angle(app, dataIMU1.quat);
                        [pS2, rS2, yS2] = funcQuat2angle(app, dataIMU2.quat);
                        anglesSensors(nCount,:) = [pS1, rS1, yS1, pS2, rS2, yS2];
                        linearAcc(nCount,:) = [dataIMU1.linAcc, dataIMU2.linAcc];
                        posSensors(nCount,:) = positionCalcFnc(app, pS1, rS1, yS1, pS2, rS2, yS2);
                        timeStamp(nCount,:) = [dataIMU1.timestamp dataIMU2.timestamp];
                        Sample(nCount,:) = nCount;
                        % Arduino reading
                        VA0(nCount,:) = readVoltage(app.Arduino, 'A0');         % Sensor 1 connected to input A0.
                        VA1(nCount,:) = readVoltage(app.Arduino, 'A1');         % Sensor 2 connected to input A1.
                        VA2(nCount,:) = readVoltage(app.Arduino, 'A2');         % Sensor 3 connected to input A2.
                        % Sync Method var
                        flagIMUData = false;
                        % Progress
                        app.StateLampLabel.Text = int2str(nCount);
                        nCount = nCount + 1;
                        drawnow()
                    end
                end
                time = toc(now);
                disp(time)
                app.StateLamp.Color = 'b';
                pause(1.0);
                % Crate File
                app.StateLampLabel.Text = 'State';
                app.Label.Text = 'SAVING DATA ...';
                folderName = 'testsData';
                [status, msg, msgID] = mkdir(folderName);
                dir = [pwd, '\',folderName];
                disp(dir)
                editField = app.EditField.Value;
                S = char(datetime('now','Format','yyyy-MM-dd''T''HHmmss'));
                fileName = [editField,Mvmt,S,'.txt'];
                try
                    fileID = fopen(fullfile(dir,fileName),'wt');
                    fprintf(fileID,'%12s %12s %12s %12s %12s %12s %12s %12s %12s %12s %12s %12s %12s %12s %12s %12s %12s %12s %12s %12s %12s %12s %12s %12s %12s\r\n','Sample','tStampIMU1','tStampIMU2','VA0', 'VA1', 'VA2', 'pos1X', 'pos1Y', 'pos1Z', 'pitchS1', 'rollS1', 'yawS1', 'linAccX1', 'linAccY1', 'linAccZ1', 'pos2X', 'pos2Y', 'pos2Z', 'pitchS2', 'rollS2', 'yawS2', 'linAccX2', 'linAccY2', 'linAccZ2', 'Angle');
                    fprintf(fileID,'%12.0f %12.2f %12.2f %12.4f %12.4f %12.4f %12.6f %12.6f %12.6f %12.6f %12.6f %12.6f %12.6f %12.6f %12.6f %12.6f %12.6f %12.6f %12.6f %12.6f %12.6f %12.6f %12.6f %12.6f %12.4f\r\n',[Sample, timeStamp(:,1), timeStamp(:,2), VA0, VA1, VA2, posSensors(:,1), posSensors(:,2), posSensors(:,3), anglesSensors(:,1), anglesSensors(:,2), anglesSensors(:,3), linearAcc(:,1), linearAcc(:,2), linearAcc(:,3), posSensors(:,4), posSensors(:,5), posSensors(:,6), anglesSensors(:,4), anglesSensors(:,5), anglesSensors(:,6), linearAcc(:,4), linearAcc(:,5), linearAcc(:,6), posSensors(:,7)]');
                    fclose(fileID);
                catch msg
                     disp(msg)
                     app.Label.Text = 'ERROR SAVING DATA';
                     pause(1)
                end
                pause(1)
                app.Label.Text = 'Ready';
                app.StateLamp.Color = 'y';
                unblockButtonsFnc(app);
            else
                while app.stopBtnPressed
                    app.Label.Text = 'Reading';
                    app.StateLamp.Color = 'b';
                    drawnow()
                end
            end
        end
    end

    methods (Access = private)

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            % Saving COMS on variables
            IMU1_COM = app.IMU1COMDropDown.Value;
            IMU2_COM = app.IMU2COMDropDown.Value;
            ARD_COM = app.ARDCOMDropDown.Value;
            % COM verification
            if app.TestModeSwitch.Value == '0'
                if isempty(IMU1_COM) || isempty(IMU2_COM) || isempty(ARD_COM) || IMU1_COM == "Select" || IMU2_COM == "Select" || ARD_COM == "Select"
                    app.Label.Text = 'Select COM';
                    return
                end
            end
            blockCOMButtonsFnc(app);
            % quat2angle installed verification
%             try
%                 [yaw, pitch, roll] = quat2angle([1 0 1 0]);
%             catch
%                 app.Label.Text = 'quat2angle not installed (aerotoolbox), please see: https://es.mathworks.com/help/aerotbx/ug/quat2angle.html';
%                 clear all;
%                 return
%             end
            app.Label.Text = 'Connecting ... ';
            app.SensorsstatusLamp.Color = 'y';
            %clc
            if app.TestModeSwitch.Value == '0'
                % ARDUINO
                try
                    disp('Arduino ...')
                    %ard = arduino('COM7', 'Mega2560');     % COM port to which the Arduino is connected
                    app.Arduino = arduino(ARD_COM, 'uno');
                catch msg
                    disp(msg)
                    app.Label.Text = 'Arduino not connected';
                    app.SensorsstatusLamp.Color = 'r';
                    unblockCOMButtonsFnc(app);
                    clear app.Arduino
                    if ~isempty(instrfind)
                        fclose(instrfind);
                        delete(instrfind);
                    end
                    %clear
                    clear all;
                    return
                end
                % IMU SENSORS
                disp('IMU sensors ...')
                COMPort1 = IMU1_COM;          % COM port to which the IMU 1 is connected
                COMPort2 = IMU2_COM;          % COM port to which the IMU 2 is connected
                baudrate = 921600;          % rate at which information is transferred
                app.lpSensor1 = lpms();         % object lpms API sensor 1 given by LPMS
                app.lpSensor2 = lpms();         % object lpms API sensor 2 given by LPMS
                if ( ~app.lpSensor1.connect(COMPort1, baudrate) || ~app.lpSensor2.connect(COMPort2, baudrate) )
                    app.Label.Text = 'IMU Sensors not connected';
                    app.SensorsstatusLamp.Color = 'r';
                    unblockCOMButtonsFnc(app);
                    %clear all;
                    return 
                end
                app.lpSensor1.setStreamingMode();
                app.lpSensor2.setStreamingMode();
            end
            % GUI visual aids
            app.Label.Text = 'Connected';
            disp('Done. All set up')
            app.SensorsstatusLamp.Color = 'g';
            app.StateLamp.Color = 'y';
            unblockButtonsFnc(app);
            app.ConnectButton.Enable = 'off';
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            disp('Disconnecting')
            app.Label.Text = 'Disconnecting';
            if app.TestModeSwitch.Value == '0'
                if (app.lpSensor1.disconnect() && app.lpSensor2.disconnect())
                    disp('Sensors disconnected')
                end
                if ~isempty(instrfind)
                    fclose(instrfind);
                    delete(instrfind);
                end
            end
            % GUI visual aids
            app.SensorsstatusLamp.Color = 'r';
            app.StateLamp.Color = 'r';
            blockButtonsFnc(app);
            unblockCOMButtonsFnc(app);
            app.ConnectButton.Enable = 'on';
            app.STOPButton.Enable = 'off';
            app.Label.Text = ' ';
            clear app.Arduino;

%             clear all
%             close all force
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            Mvmt = '_GUI_';
            app.stopBtnPressed = true;
            MovementButton(app, Mvmt);
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.stopBtnPressed = false;
            app.Label.Text = 'Stopping';
        end

        % Callback function
        function MovementTHREEButtonPushed(app, event)
            
        end

        % Value changed function: IMU1COMDropDown
        function IMU1COMDropDownValueChanged(app, event)
            app.IMU1COMDropDown.Items = getAvailableComPort(app);
        end

        % Value changed function: IMU2COMDropDown
        function IMU2COMDropDownValueChanged(app, event)
            app.IMU2COMDropDown.Items = getAvailableComPort(app);
        end

        % Value changed function: ARDCOMDropDown
        function ARDCOMDropDownValueChanged(app, event)
            app.ARDCOMDropDown.Items = getAvailableComPort(app);
        end

        % Value changed function: TestModeSwitch
        function TestModeSwitchValueChanged(app, event)
            value = app.TestModeSwitch.Value;
            if value == '0'
                app.ApptosavedatareceivedbyIMUEMGandFlexSensLabel.Text = 'App to save data received by IMU, EMG and FlexSens';
                app.UIFigure.Color = [0.94,0.94,0.94];
            else
                app.ApptosavedatareceivedbyIMUEMGandFlexSensLabel.Text = 'TEST MODE';
                app.UIFigure.Color = [0.80,0.80,0.80];
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Enable = 'off';
            app.STARTButton.Text = 'START';
            app.DisconnectButton = uibutton(app.UIFigure, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Enable = 'off';
            app.DisconnectButton.Text = 'Disconnect';
            app.SensorsstatusLampLabel = uilabel(app.UIFigure);
            app.SensorsstatusLampLabel.Text = 'Sensors status';
            app.SensorsstatusLamp = uilamp(app.UIFigure);
            app.StateLampLabel = uilabel(app.UIFigure);
            app.StateLampLabel.Text = 'State';
            app.StateLamp = uilamp(app.UIFigure);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.ApptosavedatareceivedbyIMUEMGandFlexSensLabel = uilabel(app.UIFigure);
            app.ApptosavedatareceivedbyIMUEMGandFlexSensLabel.Text = 'App to save data received by IMU, EMG and FlexSens';
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Enable = 'off';
            app.STOPButton.Text = 'STOP';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = '';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Value = 'Name';
            app.IMU1COMDropDownLabel = uilabel(app.UIFigure);
            app.IMU1COMDropDownLabel.Text = 'IMU 1 COM';
            app.IMU1COMDropDown = uidropdown(app.UIFigure);
            app.IMU1COMDropDown.Items = {'Select', 'Get COMPorts'};
            app.IMU1COMDropDown.Editable = 'on';
            app.IMU1COMDropDown.ValueChangedFcn = createCallbackFcn(app, @IMU1COMDropDownValueChanged, true);
            app.IMU1COMDropDown.Value = 'Select';
            app.IMU2COMDropDownLabel = uilabel(app.UIFigure);
            app.IMU2COMDropDownLabel.Text = 'IMU 2 COM';
            app.IMU2COMDropDown = uidropdown(app.UIFigure);
            app.IMU2COMDropDown.Items = {'Select', 'Get COMPorts'};
            app.IMU2COMDropDown.Editable = 'on';
            app.IMU2COMDropDown.ValueChangedFcn = createCallbackFcn(app, @IMU2COMDropDownValueChanged, true);
            app.IMU2COMDropDown.Value = 'Select';
            app.ARDCOMDropDownLabel = uilabel(app.UIFigure);
            app.ARDCOMDropDownLabel.Text = 'ARD COM';
            app.ARDCOMDropDown = uidropdown(app.UIFigure);
            app.ARDCOMDropDown.Items = {'Select', 'Get COMPorts'};
            app.ARDCOMDropDown.Editable = 'on';
            app.ARDCOMDropDown.ValueChangedFcn = createCallbackFcn(app, @ARDCOMDropDownValueChanged, true);
            app.ARDCOMDropDown.Value = 'Select';
            app.TestModeSwitchLabel = uilabel(app.UIFigure);
            app.TestModeSwitchLabel.Text = 'Test Mode';
            app.TestModeSwitch = uiswitch(app.UIFigure, 'slider');
            app.TestModeSwitch.Items = {'0', '1'};
            app.TestModeSwitch.ValueChangedFcn = createCallbackFcn(app, @TestModeSwitchValueChanged, true);
            app.TestModeSwitch.Value = '0';
        end
    end

    methods (Access = public)

        % Construct app
        function app = DataExoFlexAppGUIV2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end