classdef GUI_get_nIMUs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        StartButton         matlab.ui.control.Button
        StopButton          matlab.ui.control.Button
        ReadyLabel          matlab.ui.control.Label
        NameEditFieldLabel  matlab.ui.control.Label
        NameEditField       matlab.ui.control.EditField
    end

    
    properties (Access = private)
        running = false     % Global flag
        connected = false   % IMUs Connected
        COMPort = {'COM9', 'COM10', 'COM12'}; % Description
    end
    
    methods (Access = private)
        function funcSaveIMUsDataGUI(app, counter, num, date, lpData)
            fileName = [app.NameEditField.Value, 'dataIMU',num2str(num),'_', date,'.txt'];
            time = datestr(now,'HH:MM:SS FFF');
            if counter <= 1
                fileID = fopen(fileName, 'w+');
                fprintf(fileID,'%13s %12s %12s %10s %10s %10s %10s %10s %10s %10s %10s %10s %10s %10s %10s %10s %10s %10s %10s %10s %10s %10s\r\n',...
                    'ClockTime', 'TimeStamp','Sample',...
                    'gyrX','gyrY','gyrZ','accX','accY','accZ','magX','magY','magZ',...
                    'quat1','quat2','quat2','quat2','eulerX','eulerY','eulerZ',...
                    'linAccX','linAccY','linAccZ');
            else
                counter = counter - 1;
                fileID = fopen(fileName, 'a');
                fprintf(fileID,'%13s %12.2f %12.0f %10.4f %10.4f %10.4f %10.4f %10.4f %10.4f %10.4f %10.4f %10.4f %10.4f %10.4f %10.4f %10.4f %10.4f %10.4f %10.4f %10.4f %10.4f %10.4f\r\n',...
                    time, lpData.timestamp, counter,...
                    lpData.gyr, lpData.acc, lpData.mag,...
                    lpData.quat, lpData.euler, lpData.linAcc);
            end
            fclose(fileID);
        end
    
        function clearSerialPorts(app)
            if ~isempty(instrfind)
                fclose(instrfind);
                delete(instrfind);
            end
        end
    end
    

    methods (Access = private)

        % Button pushed function: StartButton
        function Start_Bttn(app, event)
            app.running = true;
            app.ReadyLabel.Text = 'Start';
            date = char(datetime('now','Format','yyyy-MM-dd''T''HHmmss'));
            
            clearSerialPorts(app)
            
            app.ReadyLabel.Text = 'Connecting...';
            app.StartButton.Enable = false;
            [~,nIMUs] = size(app.COMPort);
            baudrate = 921600;
            counter = 1;
            % create nIMUs instances
            for imu = 1:nIMUs
                lpSensor(imu) = lpms();
            end
            % Connect to sensor
            for imu = 1:nIMUs
                if ( ~lpSensor(imu).connect(app.COMPort(imu), baudrate) )
                    return 
                end
            end
            % Set streaming mode
            for imu = 1:nIMUs
                lpSensor(imu).setStreamingMode();
            end
            
            app.ReadyLabel.Text = 'Reading';
            
            while app.running
                for imu = 1:nIMUs
                    lpData = lpSensor(imu).getQueueSensorData();
                    if ~isempty(lpData)
                        funcSaveIMUsDataGUI(app, counter, imu, date, lpData);
                    end
                end
                counter = counter + 1;
                % disp(counter)
                % pause (0.001)
                drawnow
            end
            
            app.ReadyLabel.Text = 'Disconnecting...';
            % disconnecting
            
            for imu = 1:nIMUs
                if (lpSensor(imu).disconnect())
                    %app.ReadyLabel.Text = 'sensor disconnected';
                    disp('sensor disconnected')
                end
            end
            
            app.ReadyLabel.Text = 'Ready';
            % bye
            app.StartButton.Enable = true;
        end

        % Button pushed function: StopButton
        function Stop_Bttn(app, event)
            app.running = false;
            app.ReadyLabel.Text = 'Stop';
        end

        % Callback function
        function SwitchValueChanged(app, event)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @Start_Bttn, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @Stop_Bttn, true);
            app.StopButton.Text = 'Stop';
            app.ReadyLabel = uilabel(app.UIFigure);
            app.ReadyLabel.Text = 'Ready';
            app.NameEditFieldLabel = uilabel(app.UIFigure);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.UIFigure, 'text');
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI_get_nIMUs

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end